classdef Auroom < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainFrame               matlab.ui.Figure                   % Auroom
        Label                   matlab.ui.control.Label            % Auroom
        Label2                  matlab.ui.control.Label            % Listen...
        MainTabGroup            matlab.ui.container.TabGroup       % Scene ...
        SceneSettingsTab        matlab.ui.container.Tab            % Scene ...
        SourcePanel             matlab.ui.container.Panel          % Source
        Label3                  matlab.ui.control.Label            % Position:
        LabelNumericEditField   matlab.ui.control.Label            % X:
        xSrcPosField            matlab.ui.control.NumericEditField % [-Inf ...
        LabelNumericEditField2  matlab.ui.control.Label            % Y:
        ySrcPosField            matlab.ui.control.NumericEditField % [-Inf ...
        LabelNumericEditField3  matlab.ui.control.Label            % Z:
        zSrcPosField            matlab.ui.control.NumericEditField % [-Inf ...
        srcApplyButton          matlab.ui.control.Button           % Apply
        Label9                  matlab.ui.control.Label            % samples
        Label10                 matlab.ui.control.Label            % samples
        Label11                 matlab.ui.control.Label            % samples
        ReceiverPanel           matlab.ui.container.Panel          % Receiver
        Label4                  matlab.ui.control.Label            % Position:
        Label5                  matlab.ui.control.Label            % X:
        xRecPosField            matlab.ui.control.NumericEditField % [-Inf ...
        Label6                  matlab.ui.control.Label            % Y:
        yRecPosField            matlab.ui.control.NumericEditField % [-Inf ...
        Label7                  matlab.ui.control.Label            % Z:
        zRecPosField            matlab.ui.control.NumericEditField % [-Inf ...
        recApplyButton          matlab.ui.control.Button           % Apply
        Label8                  matlab.ui.control.Label            % Orient...
        LabelNumericEditField4  matlab.ui.control.Label            % Phi:
        azimuthAngleField       matlab.ui.control.NumericEditField % [-Inf ...
        Label12                 matlab.ui.control.Label            % samples
        Label13                 matlab.ui.control.Label            % samples
        Label14                 matlab.ui.control.Label            % samples
        Label15                 matlab.ui.control.Label            % degrees
        Label16                 matlab.ui.control.Label            % Detect...
        LabelNumericEditField5  matlab.ui.control.Label            % R:
        detectorSizeField       matlab.ui.control.NumericEditField % [0 Inf]
        Label17                 matlab.ui.control.Label            % samples
        RoomPanel               matlab.ui.container.Panel          % Room
        LoadRoomFileButton      matlab.ui.control.Button           % Load new
        LabelListBox            matlab.ui.control.Label            % Wall m...
        MaterialsBox            matlab.ui.control.ListBox         
        AddMaterialButton       matlab.ui.control.Button           % Add
        SetMaterialButton       matlab.ui.control.Button           % Set
        Label26                 matlab.ui.control.Label            % Used r...
        MaterialNameField       matlab.ui.control.EditField       
        usedRoomFileField       matlab.ui.control.EditField        % rect.roo
        LabelNumericEditField9  matlab.ui.control.Label            % Sample...
        Sample2MeterRatioField  matlab.ui.control.NumericEditField % [0 Inf]
        SetSample2MeterRatioButton matlab.ui.control.Button % Set
        SimulationSettingsTab   matlab.ui.container.Tab            % Simula...
        LabelNumericEditField6  matlab.ui.control.Label            % Impuls...
        IrLengthField           matlab.ui.control.NumericEditField % [0 Inf]
        Label18                 matlab.ui.control.Label            % seconds
        LabelNumericEditField7  matlab.ui.control.Label            % Number...
        NumberOfParticlesField  matlab.ui.control.NumericEditField % [0 Inf]
        EnableReceiverTotalAttCheckBox matlab.ui.control.CheckBox % Enable ...
        EnableRecAtt            matlab.ui.control.Button           % Apply
        ApplyNumberOfParticles  matlab.ui.control.Button           % Apply
        ApplyIrLength           matlab.ui.control.Button           % Apply
        SaveBIRToFileButton     matlab.ui.control.Button           % Save b...
        AuralizationSettingsTab matlab.ui.container.Tab            % Aurali...
        LabelEditField2         matlab.ui.control.Label            % Name o...
        SoundFileNameField      matlab.ui.control.EditField       
        Label21                 matlab.ui.control.Label            % samples
        LabelDiscreteKnob       matlab.ui.control.Label            % Aurali...
        ModeKnob                matlab.ui.control.DiscreteKnob     % Play, ...
        LabelNumericEditField8  matlab.ui.control.Label            % Stream...
        FrameLengthField        matlab.ui.control.NumericEditField % [0 Inf]
        AuralizePanel           matlab.ui.container.Panel         
        Label19                 matlab.ui.control.Label            % Auralize!
        ToggleSwitch            matlab.ui.control.ToggleSwitch     % Off, On
        SimulationPanel         matlab.ui.container.Panel         
        Label25                 matlab.ui.control.Label            % Simula...
        StartSimulationButton   matlab.ui.control.Button           % Start
        LoadExistingBIRPanel    matlab.ui.container.Panel          % Load e...
        LabelEditField          matlab.ui.control.Label            % Filename
        BIRFilenameField        matlab.ui.control.EditField       
        LoadBIRButton           matlab.ui.control.Button           % Load
    end

    properties (Access = private)
        simulationContext Simulation3dContext; % Description
        
        isSimulationReady     =     false;
    end
    
    methods (Access = private)
        function disableSimulationAndAuralization(app)
            app.StartSimulationButton.Enable    =   'off';
            app.ToggleSwitch.Enable             =   'off';
        end
        
        function disableSettingsButtons(app)
            value = 'off';
            app.srcApplyButton.Enable                   =   value;
            app.recApplyButton.Enable                   =   value;
            app.LoadRoomFileButton.Enable               =   value;
            app.AddMaterialButton.Enable                =   value;
            app.SetMaterialButton.Enable                =   value;
            app.SetSample2MeterRatioButton.Enable       =   value;
            app.EnableReceiverTotalAttCheckBox.Enable   =   value;
            app.EnableRecAtt.Enable                     =   value;
            app.ApplyNumberOfParticles.Enable           =   value;
            app.ApplyIrLength.Enable                    =   value;
            app.SaveBIRToFileButton.Enable              =   value;
            app.ModeKnob.Enable                         =   value;
            app.LoadBIRButton.Enable                    =   value;
        end
        
        function enableSettingsButtons(app)
            value = 'on';
            app.srcApplyButton.Enable                   =   value;
            app.recApplyButton.Enable                   =   value;
            app.LoadRoomFileButton.Enable               =   value;
            app.AddMaterialButton.Enable                =   value;
            app.SetMaterialButton.Enable                =   value;
            app.SetSample2MeterRatioButton.Enable       =   value;
            app.EnableReceiverTotalAttCheckBox.Enable   =   value;
            app.EnableRecAtt.Enable                     =   value;
            app.ApplyNumberOfParticles.Enable           =   value;
            app.ApplyIrLength.Enable                    =   value;
            app.SaveBIRToFileButton.Enable              =   value;
            app.ModeKnob.Enable                         =   value;
            app.LoadBIRButton.Enable                    =   value;
        end
        
        function enableSimulationAndAuralization(app)
            app.StartSimulationButton.Enable    =   'on';
            app.ToggleSwitch.Enable             =   'on';
        end
        
        function initializeSimulator(app)
            try
                app.simulationContext           =   Simulation3dContext();
            catch ME
                msgbox(ME.message, 'Error','error');
                return;
            end
            app.enableSimulation();
        end

        function disableSimulation(app)
            app.StartSimulationButton.Enable    =   'off';
        end

        function enableSimulation(app)
            app.StartSimulationButton.Enable    =   'on';
        end

        function enableAuralization(app)
            app.ToggleSwitch.Enable             =   'on';
        end
        
        function disableAuralization(app)
            app.ToggleSwitch.Enable             =   'off';
        end
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           initializeSimulator(app);
        end

        % srcApplyButton button pushed function
        function applySourceSettings(app)
           app.disableSimulationAndAuralization();
           app.disableSettingsButtons();
           
           x                  =     app.xSrcPosField.Value;
           y                  =     app.ySrcPosField.Value;
           z                  =     app.zSrcPosField.Value;
           positionVector     =     Vec3d(x, y, z);
           
           app.simulationContext.setRecalcGeometryFlag(true);
           app.simulationContext.createSourceModel(positionVector);
           
           app.enableSettingsButtons();
           app.enableSimulationAndAuralization();
        end

        % recApplyButton button pushed function
        function applyReceiverSettings(app)
           app.disableSimulationAndAuralization();
           app.disableSettingsButtons();
           
           x                  =     app.xRecPosField.Value;
           y                  =     app.yRecPosField.Value;
           z                  =     app.zRecPosField.Value;
           positionVector     =     Vec3d(x, y, z);
           
           app.simulationContext.setRecalcGeometryFlag(true);
           app.simulationContext.createReceiverModel(positionVector, app.azimuthAngleField.Value, app.detectorSizeField.Value);
           
           app.enableSettingsButtons();
           app.enableSimulationAndAuralization();
        end

        % LoadRoomFileButton button pushed function
        function loadRoomFile(app)
            app.disableSimulationAndAuralization();
            app.disableSettingsButtons();
            
            [filename]     =     uigetfile('*.roo', 'Load room from file');
            
            if filename == 0
                app.enableSettingsButtons();
                app.enableSimulationAndAuralization();
                return;
            end
            
            try
                path                  =     sprintf('../rooms/%s', filename');
                [vertices, faces]     =     parseRoomFile(path);
                
                app.simulationContext.createRoomModel(vertices, faces);
            catch ME
                msgbox(ME.message, 'Error','error');
                app.enableSettingsButtons();
                app.enableSimulationAndAuralization();
                return;
            end
            
            app.usedRoomFileField.Value     =     filename;
            app.simulationContext.setRecalcGeometryFlag(true);
            
            app.enableSettingsButtons();
            app.enableSimulationAndAuralization();
        end

        % AddMaterialButton button pushed function
        function addMaterialButtonPushed(app)
            app.disableSimulationAndAuralization();
            app.disableSettingsButtons();
            
            materialName     =     app.MaterialNameField.Value;
            filename         =     uigetfile('*.wav', 'Choose impulse response file');
            path             =     sprintf('../materials/%s', filename);
            
            if filename == 0
                return;
            end
            
            try
                [ir, fs]     =     audioread(path);
            catch ME
                msgbox(ME.message, 'Error','error');
                app.enableSettingsButtons();
                app.enableSimulationAndAuralization();
                return;
            end
            
            material                             =     Material(materialName, Filter(1, ir, fs));
            app.MaterialsBox.Items{end + 1}      =     material.getName();
            app.simulationContext.addMaterial(material);
            msgbox('Material added', 'Success');
            
            app.enableSettingsButtons();
            app.enableSimulationAndAuralization();
        end

        % SetMaterialButton button pushed function
        function SetMaterialButtonPushed(app)
            app.disableSimulationAndAuralization();
            app.disableSettingsButtons();
            
            materialName     =     app.MaterialsBox.Value;
            try
                app.simulationContext.setMaterial(materialName);
            catch ME
                msgbox(ME.message, 'Warning');
                app.enableSettingsButtons();
                app.enableSimulationAndAuralization();
                return;
            end
            
            app.simulationContext.setRecalcIrFlag(true);
            
            app.enableSettingsButtons();
            app.enableSimulationAndAuralization();
        end

        % ToggleSwitch value changed function
        function ToggleSwitchValueChanged(app)
            app.disableSettingsButtons();
            app.disableSimulationAndAuralization();
            
            value     =     app.ToggleSwitch.Value;
            
            if ~app.isSimulationReady
                msgbox('You should first simulate the sound field.', 'Warning');
                app.ToggleSwitch.Value     =     'Off';
                app.enableSimulationAndAuralization();
                app.enableSettingsButtons();
                return;
            end
            
            if strcmp(value, 'On')
                [left, right]       =     app.simulationContext.getFilters();
                writeToFile         =     false;
                
                if strcmp(app.ModeKnob.Value, 'Play')
                    writeToFile     =     false;
                elseif strcmp(app.ModeKnob.Value, 'Write to file')
                    writeToFile     =     true;
                end
                
                try 
                    app.simulationContext.auralize(app.SoundFileNameField.Value, app.FrameLengthField.Value, left, right, writeToFile);                 
                catch ME
                    msgbox(ME.message, 'Error','error');
                    app.enableSimulationAndAuralization();
                    app.ToggleSwitch.Value     =     'Off';
                    return;
                end
                
                msgbox('Auralization done!', 'Success!');
                
                app.ToggleSwitch.Value         =     'Off';
                app.enableSettingsButtons();
                app.enableAuralization();
            elseif strcmp(value, 'Off')
                app.enableSettingsButtons();
                app.enableSimulation();
            end
        end

        % StartSimulationButton button pushed function
        function StartSimulationButtonPushed(app)
            app.disableSimulationAndAuralization();
            app.disableSettingsButtons();
            
            try
                app.simulationContext.start();
            catch ME
                msgbox(ME.message, 'Warning');
                app.enableSimulationAndAuralization();
                app.enableSettingsButtons();
                return;
            end
            
            app.simulationContext.setRecalcGeometryFlag(false);
            app.simulationContext.setRecalcIrFlag(false);
            app.isSimulationReady     =     true;
            msgbox('Simulation done!', 'Success!');
            
            app.enableSettingsButtons();
            app.enableAuralization();
        end

        % ApplyIrLength button pushed function
        function ApplyIrLengthButtonPushed(app)
            app.disableSimulationAndAuralization();
            app.disableSettingsButtons();
            
            app.simulationContext.setIrLength(app.IrLengthField.Value);
            app.simulationContext.setRecalcGeometryFlag(true);
            
            app.enableSettingsButtons();    
            app.enableSimulationAndAuralization();
        end

        % ApplyNumberOfParticles button pushed function
        function ApplyNumberOfParticlesButtonPushed(app)
            app.disableSimulationAndAuralization();
            app.disableSettingsButtons();
            
            app.simulationContext.setParticlesNumber(app.NumberOfParticlesField.Value);
            app.simulationContext.setRecalcGeometryFlag(true);
            
            app.enableSettingsButtons();
            app.enableSimulationAndAuralization();
        end

        % EnableRecAtt button pushed function
        function EnableRecAttButtonPushed(app)
            app.disableSimulationAndAuralization();
            app.disableSettingsButtons();
            
            app.simulationContext.setAttenuationOnHead(app.EnableReceiverTotalAttCheckBox.Value);
            app.simulationContext.setRecalcIrFlag(true);
            
            app.enableSettingsButtons();
            app.enableSimulationAndAuralization();
        end

        % SetSample2MeterRatioButton button pushed function
        function SetSample2MeterRatioButtonPushed(app)
            app.disableSimulationAndAuralization();
            app.disableSettingsButtons();
            
            setPixel2MeterRatio(app.Sample2MeterRatioField.Value);
            app.simulationContext.setRecalcIrFlag(true);
            msgbox('Ratio successfully changed.', 'Success');
            
            app.enableSettingsButtons();
            app.enableSimulationAndAuralization();
        end

        % SaveBIRToFileButton button pushed function
        function SaveBIRToFileButtonPushed(app)
            app.disableSimulationAndAuralization();
            app.disableSettingsButtons();
            
            try
                filename     =     app.simulationContext.saveBinauralImpulseResponseToFile();
            catch ME
                msgbox(ME.message, 'Warning');
                app.enableSettingsButtons();
                app.enableSimulationAndAuralization();
                return;
            end
            
            msg     =     sprintf('Saved to file %s', filename);
            msgbox(msg, 'Success');
            
            app.enableSettingsButtons();
            app.enableSimulationAndAuralization();
        end

        % LoadBIRButton button pushed function
        function LoadBIRButtonPushed(app)
            app.disableSimulationAndAuralization();
            app.disableSettingsButtons();
            
            filename     =     sprintf('../bir/%s', app.BIRFilenameField.Value);
            
            try
                app.simulationContext.setFilters(filename);
            catch ME
                msgbox(ME.message, 'Error', 'error');
                app.enableSettingsButtons();
                app.enableSimulationAndAuralization();
                return;
            end
            
            msgbox('Loading BIR was successful.', 'Sucess');
            app.isSimulationReady     =     true;
            
            app.enableSettingsButtons();
            app.enableSimulationAndAuralization();
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainFrame = uifigure;
            app.MainFrame.Name = 'Auroom';
            setAutoResize(app, app.MainFrame, false)
            app.Label = uilabel(app.MainFrame);
            app.Label.Text = 'Auroom';
            app.Label2 = uilabel(app.MainFrame);
            app.Label2.Text = 'Listen to your room.';
            app.MainTabGroup = uitabgroup(app.MainFrame);
            app.MainTabGroup.Units = 'pixels';
            app.SceneSettingsTab = uitab(app.MainTabGroup);
            app.SceneSettingsTab.Units = 'pixels';
            app.SceneSettingsTab.Title = 'Scene settings';
            app.SourcePanel = uipanel(app.SceneSettingsTab);
            app.SourcePanel.Title = 'Source';
            app.SourcePanel.Units = 'pixels';
            app.Label3 = uilabel(app.SourcePanel);
            app.Label3.Text = 'Position:';
            app.LabelNumericEditField = uilabel(app.SourcePanel);
            app.LabelNumericEditField.Text = 'X:';
            app.xSrcPosField = uieditfield(app.SourcePanel, 'numeric');
            app.xSrcPosField.Value = 500;
            app.LabelNumericEditField2 = uilabel(app.SourcePanel);
            app.LabelNumericEditField2.Text = 'Y:';
            app.ySrcPosField = uieditfield(app.SourcePanel, 'numeric');
            app.ySrcPosField.Value = 1000;
            app.LabelNumericEditField3 = uilabel(app.SourcePanel);
            app.LabelNumericEditField3.Text = 'Z:';
            app.zSrcPosField = uieditfield(app.SourcePanel, 'numeric');
            app.zSrcPosField.Value = 300;
            app.srcApplyButton = uibutton(app.SourcePanel, 'push');
            app.srcApplyButton.ButtonPushedFcn = createCallbackFcn(app, @applySourceSettings);
            app.srcApplyButton.Text = 'Apply';
            app.Label9 = uilabel(app.SourcePanel);
            app.Label9.Text = 'samples';
            app.Label10 = uilabel(app.SourcePanel);
            app.Label10.Text = 'samples';
            app.Label11 = uilabel(app.SourcePanel);
            app.Label11.Text = 'samples';
            app.ReceiverPanel = uipanel(app.SceneSettingsTab);
            app.ReceiverPanel.Title = 'Receiver';
            app.ReceiverPanel.Units = 'pixels';
            app.Label4 = uilabel(app.ReceiverPanel);
            app.Label4.Text = 'Position:';
            app.Label5 = uilabel(app.ReceiverPanel);
            app.Label5.Text = 'X:';
            app.xRecPosField = uieditfield(app.ReceiverPanel, 'numeric');
            app.xRecPosField.Value = 500;
            app.Label6 = uilabel(app.ReceiverPanel);
            app.Label6.Text = 'Y:';
            app.yRecPosField = uieditfield(app.ReceiverPanel, 'numeric');
            app.yRecPosField.Value = 500;
            app.Label7 = uilabel(app.ReceiverPanel);
            app.Label7.Text = 'Z:';
            app.zRecPosField = uieditfield(app.ReceiverPanel, 'numeric');
            app.zRecPosField.Value = 300;
            app.recApplyButton = uibutton(app.ReceiverPanel, 'push');
            app.recApplyButton.ButtonPushedFcn = createCallbackFcn(app, @applyReceiverSettings);
            app.recApplyButton.Text = 'Apply';
            app.Label8 = uilabel(app.ReceiverPanel);
            app.Label8.Text = 'Orientation (azimuth angle):';
            app.LabelNumericEditField4 = uilabel(app.ReceiverPanel);
            app.LabelNumericEditField4.Text = 'Phi:';
            app.azimuthAngleField = uieditfield(app.ReceiverPanel, 'numeric');
            app.azimuthAngleField.Value = 180;
            app.Label12 = uilabel(app.ReceiverPanel);
            app.Label12.Text = 'samples';
            app.Label13 = uilabel(app.ReceiverPanel);
            app.Label13.Text = 'samples';
            app.Label14 = uilabel(app.ReceiverPanel);
            app.Label14.Text = 'samples';
            app.Label15 = uilabel(app.ReceiverPanel);
            app.Label15.Text = 'degrees';
            app.Label16 = uilabel(app.ReceiverPanel);
            app.Label16.Text = 'Detecting sphere''s radius:';
            app.LabelNumericEditField5 = uilabel(app.ReceiverPanel);
            app.LabelNumericEditField5.Text = 'R:';
            app.detectorSizeField = uieditfield(app.ReceiverPanel, 'numeric');
            app.detectorSizeField.LowerLimitInclusive = 'off';
            app.detectorSizeField.Limits = [0 Inf];
            app.detectorSizeField.Value = 150;
            app.Label17 = uilabel(app.ReceiverPanel);
            app.Label17.Text = 'samples';
            app.RoomPanel = uipanel(app.SceneSettingsTab);
            app.RoomPanel.Title = 'Room';
            app.RoomPanel.Units = 'pixels';
            app.LoadRoomFileButton = uibutton(app.RoomPanel, 'push');
            app.LoadRoomFileButton.ButtonPushedFcn = createCallbackFcn(app, @loadRoomFile);
            app.LoadRoomFileButton.Text = 'Load new';
            app.LabelListBox = uilabel(app.RoomPanel);
            app.LabelListBox.Text = 'Wall materials list:';
            app.MaterialsBox = uilistbox(app.RoomPanel);
            app.MaterialsBox.Items = {};
            app.MaterialsBox.Value = {};
            app.AddMaterialButton = uibutton(app.RoomPanel, 'push');
            app.AddMaterialButton.ButtonPushedFcn = createCallbackFcn(app, @addMaterialButtonPushed);
            app.AddMaterialButton.Text = 'Add';
            app.SetMaterialButton = uibutton(app.RoomPanel, 'push');
            app.SetMaterialButton.ButtonPushedFcn = createCallbackFcn(app, @SetMaterialButtonPushed);
            app.SetMaterialButton.Text = 'Set';
            app.Label26 = uilabel(app.RoomPanel);
            app.Label26.Text = 'Used room file:';
            app.MaterialNameField = uieditfield(app.RoomPanel, 'text');
            app.usedRoomFileField = uieditfield(app.RoomPanel, 'text');
            app.usedRoomFileField.Editable = 'off';
            app.usedRoomFileField.Value = 'rect.roo';
            app.LabelNumericEditField9 = uilabel(app.RoomPanel);
            app.LabelNumericEditField9.Text = 'Sample to meter ratio:';
            app.Sample2MeterRatioField = uieditfield(app.RoomPanel, 'numeric');
            app.Sample2MeterRatioField.LowerLimitInclusive = 'off';
            app.Sample2MeterRatioField.Limits = [0 Inf];
            app.Sample2MeterRatioField.Value = 200;
            app.SetSample2MeterRatioButton = uibutton(app.RoomPanel, 'push');
            app.SetSample2MeterRatioButton.ButtonPushedFcn = createCallbackFcn(app, @SetSample2MeterRatioButtonPushed);
            app.SetSample2MeterRatioButton.Text = 'Set';
            app.SimulationSettingsTab = uitab(app.MainTabGroup);
            app.SimulationSettingsTab.Units = 'pixels';
            app.SimulationSettingsTab.Title = 'Simulation settings';
            app.LabelNumericEditField6 = uilabel(app.SimulationSettingsTab);
            app.LabelNumericEditField6.Text = 'Impulse response''s length:';
            app.IrLengthField = uieditfield(app.SimulationSettingsTab, 'numeric');
            app.IrLengthField.LowerLimitInclusive = 'off';
            app.IrLengthField.Limits = [0 Inf];
            app.IrLengthField.Value = 1;
            app.Label18 = uilabel(app.SimulationSettingsTab);
            app.Label18.Text = 'seconds';
            app.LabelNumericEditField7 = uilabel(app.SimulationSettingsTab);
            app.LabelNumericEditField7.Text = 'Number of sound particles:';
            app.NumberOfParticlesField = uieditfield(app.SimulationSettingsTab, 'numeric');
            app.NumberOfParticlesField.LowerLimitInclusive = 'off';
            app.NumberOfParticlesField.Limits = [0 Inf];
            app.NumberOfParticlesField.Value = 1;
            app.EnableReceiverTotalAttCheckBox = uicheckbox(app.SimulationSettingsTab);
            app.EnableReceiverTotalAttCheckBox.Text = 'Enable total attenuation on receiver';
            app.EnableRecAtt = uibutton(app.SimulationSettingsTab, 'push');
            app.EnableRecAtt.ButtonPushedFcn = createCallbackFcn(app, @EnableRecAttButtonPushed);
            app.EnableRecAtt.Text = 'Apply';
            app.ApplyNumberOfParticles = uibutton(app.SimulationSettingsTab, 'push');
            app.ApplyNumberOfParticles.ButtonPushedFcn = createCallbackFcn(app, @ApplyNumberOfParticlesButtonPushed);
            app.ApplyNumberOfParticles.Text = 'Apply';
            app.ApplyIrLength = uibutton(app.SimulationSettingsTab, 'push');
            app.ApplyIrLength.ButtonPushedFcn = createCallbackFcn(app, @ApplyIrLengthButtonPushed);
            app.ApplyIrLength.Text = 'Apply';
            app.SaveBIRToFileButton = uibutton(app.SimulationSettingsTab, 'push');
            app.SaveBIRToFileButton.ButtonPushedFcn = createCallbackFcn(app, @SaveBIRToFileButtonPushed);
            app.SaveBIRToFileButton.Text = 'Save binaural impulse response to file';
            app.AuralizationSettingsTab = uitab(app.MainTabGroup);
            app.AuralizationSettingsTab.Units = 'pixels';
            app.AuralizationSettingsTab.Title = 'Auralization settings';
            app.LabelEditField2 = uilabel(app.AuralizationSettingsTab);
            app.LabelEditField2.Text = 'Name of the soundfile you want to auralize:';
            app.SoundFileNameField = uieditfield(app.AuralizationSettingsTab, 'text');
            app.Label21 = uilabel(app.AuralizationSettingsTab);
            app.Label21.Text = 'samples';
            app.LabelDiscreteKnob = uilabel(app.AuralizationSettingsTab);
            app.LabelDiscreteKnob.Text = 'Auralization mode';
            app.ModeKnob = uiknob(app.AuralizationSettingsTab, 'discrete');
            app.ModeKnob.Items = {'Play', 'Write to file'};
            app.ModeKnob.Value = 'Play';
            app.LabelNumericEditField8 = uilabel(app.AuralizationSettingsTab);
            app.LabelNumericEditField8.Text = 'Stream processing frame length:';
            app.FrameLengthField = uieditfield(app.AuralizationSettingsTab, 'numeric');
            app.FrameLengthField.Limits = [0 Inf];
            app.FrameLengthField.Value = 4096;
            app.LoadExistingBIRPanel = uipanel(app.AuralizationSettingsTab);
            app.LoadExistingBIRPanel.Title = 'Load existing binaural impulse response';
            app.LoadExistingBIRPanel.Units = 'pixels';
            app.LabelEditField = uilabel(app.LoadExistingBIRPanel);
            app.LabelEditField.Text = 'Filename';
            app.BIRFilenameField = uieditfield(app.LoadExistingBIRPanel, 'text');
            app.LoadBIRButton = uibutton(app.LoadExistingBIRPanel, 'push');
            app.LoadBIRButton.ButtonPushedFcn = createCallbackFcn(app, @LoadBIRButtonPushed);
            app.LoadBIRButton.Text = 'Load';
            app.AuralizePanel = uipanel(app.MainFrame);
            app.AuralizePanel.Units = 'pixels';
            app.Label19 = uilabel(app.AuralizePanel);
            app.Label19.Text = 'Auralize!';
            app.ToggleSwitch = uiswitch(app.AuralizePanel, 'toggle');
            app.ToggleSwitch.ValueChangedFcn = createCallbackFcn(app, @ToggleSwitchValueChanged);
            app.ToggleSwitch.Enable = 'off';
            app.SimulationPanel = uipanel(app.MainFrame);
            app.SimulationPanel.Units = 'pixels';
            app.Label25 = uilabel(app.SimulationPanel);
            app.Label25.Text = 'Simulate sound field';
            app.StartSimulationButton = uibutton(app.SimulationPanel, 'push');
            app.StartSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @StartSimulationButtonPushed);
            app.StartSimulationButton.Enable = 'off';
            app.StartSimulationButton.Text = 'Start';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Auroom()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainFrame)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainFrame)
        end
    end
end